classdef registration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        ManualTab                    matlab.ui.container.Tab
        RegisterManuallyButton       matlab.ui.control.Button
        UIAxes4                      matlab.ui.control.UIAxes
        UIAxes5                      matlab.ui.control.UIAxes
        UIAxes6                      matlab.ui.control.UIAxes
        AutomaticTab                 matlab.ui.container.Tab
        RegisterAutomaticallyButton  matlab.ui.control.Button
        UIAxes7                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function registerAuto(app)
            
            
        end

        % Button pushed function: RegisterManuallyButton
        function register(app, event)
            fixed = imread("images\fixed.jpg");
            move = imread("images\moving.jpg");
            
            % Manual selection of image points.
            [movingPoints, fixedPoints] = cpselect(fixed, move, 'Wait', true);
            
            % Projective Homography
            t = fitgeotrans(movingPoints,fixedPoints,'projective');
            
            % Applying the transformation to the moving image
            Rfixed = imref2d(size(move));
            registered = imwarp(fixed,t,'OutputView',Rfixed);
            imshow(fixed, 'Parent', app.UIAxes4);
            imshow(move, 'Parent', app.UIAxes5);
            imshowpair(registered, fixed, 'blend', 'Parent', app.UIAxes6);
        end

        % Button pushed function: RegisterAutomaticallyButton
        function RegisterAutomatically(app, event)
            % Load image directory
            playset = imageDatastore("images\playset\");
            
            % Image rotation correction
            I = imrotate(readimage(playset,1),-90);
            
            [x,y] = size(I);
            
            % Convert to grayscale
            grayImage = rgb2gray(I);
            % Find points
            points = detectHarrisFeatures(grayImage);
            [features, points] = extractFeatures(grayImage, points);
            
            tforms(3) = projective2d(eye(3));
            
            imageSize = zeros(3,2);
            
            for n = 2:3
                pointsPrevious = points;
                featuresPrevious = features;
                
                I = readimage(playset, n);
                I = imrotate(I,-90);
                grayImage = rgb2gray(I);
                
                imageSize(n,:)  = size(grayImage);
                
                points = detectHarrisFeatures(grayImage);
                [features, points] = extractFeatures(grayImage, points);
                
                indexPairs = matchFeatures(features, featuresPrevious, 'Unique', true);
                
                matchedPoints = points(indexPairs(:,1), :);
                matchedPointsPrev = pointsPrevious(indexPairs(:,2), :);
                
                % RANSAC algorithm for Images 1-3 in pairs
                tforms(n) = estimateGeometricTransform2D(matchedPoints, matchedPointsPrev,'projective');
                
                % Compute transforms for each pair compared to the next
                tforms(n).T = tforms(n).T * tforms(n-1).T;
            end
            
            for i = 1:numel(tforms)
                [xlim(i,:), ylim(i,:)] = outputLimits(tforms(i), [1 imageSize(i,2)], [1 imageSize(i,1)]);
            end
            
            avgXLim = mean(xlim, 2);
            
            [~, idx] = sort(avgXLim);
            
            centerIdx = floor((numel(tforms)+1)/2);
            
            centerImageIdx = idx(centerIdx);
            
            Tinv = invert(tforms(centerImageIdx));
            
            for i = 1:numel(tforms)
                tforms(i).T = tforms(i).T * Tinv.T;
            end
            
            for i = 1:numel(tforms)
                [xlim(i,:), ylim(i,:)] = outputLimits(tforms(i), [1 imageSize(i,2)], [1 imageSize(i,1)]);
            end
            
            maxImageSize = max(imageSize);
            
            % Find the minimum and maximum output limits
            xMin = min([1; xlim(:)]);
            xMax = max([maxImageSize(2); xlim(:)]);
            
            yMin = min([1; ylim(:)]);
            yMax = max([maxImageSize(1); ylim(:)]);
            
            % Initialize the "empty" panorama.
            panorama = zeros([x y 3], 'like', I);
            
            blender = vision.AlphaBlender('Operation', 'Binary mask','MaskSource', 'Input port');
            
            % Create a 2-D spatial reference object defining the size of the panorama.
            xLimits = [xMin xMax];
            yLimits = [yMin yMax];
            panoramaView = imref2d([x y], xLimits, yLimits);
            
            % Create the panorama.
            for i = 1:3
                
                I = readimage(playset, i);
                I = imrotate(I, -90);
                
                warpedImage = imwarp(I, tforms(i), 'OutputView', panoramaView);
                mask = imwarp(true(size(I,1),size(I,2)), tforms(i), 'OutputView', panoramaView);
                
                % Overlay the warpedImage onto the panorama.
                panorama = step(blender, panorama, warpedImage, mask);
            end
            
            figure
            imshow(panorama)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ManualTab = uitab(app.TabGroup);
            app.ManualTab.Title = 'Manual ';
            app.RegisterManuallyButton = uibutton(app.ManualTab, 'push');
            app.RegisterManuallyButton.ButtonPushedFcn = createCallbackFcn(app, @register, true);
            app.RegisterManuallyButton.Text = 'Register Manually';
            app.UIAxes4 = uiaxes(app.ManualTab);
            title(app.UIAxes4, 'Image 1')
            app.UIAxes4.Visible = 'off';
            app.UIAxes5 = uiaxes(app.ManualTab);
            title(app.UIAxes5, 'Image 2')
            app.UIAxes5.Visible = 'off';
            app.UIAxes6 = uiaxes(app.ManualTab);
            title(app.UIAxes6, 'Registered Image')
            app.UIAxes6.Visible = 'off';
            app.AutomaticTab = uitab(app.TabGroup);
            app.AutomaticTab.Title = 'Automatic';
            app.RegisterAutomaticallyButton = uibutton(app.AutomaticTab, 'push');
            app.RegisterAutomaticallyButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterAutomatically, true);
            app.RegisterAutomaticallyButton.Text = 'Register Automatically ';
            app.UIAxes7 = uiaxes(app.AutomaticTab);
            title(app.UIAxes7, 'Automatically Registered Image')
            app.UIAxes7.Visible = 'off';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = registration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @registerAuto)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end